classdef HoverButton < matlab.ui.componentcontainer.ComponentContainer
    %HOVERBUTTON Button component that changes appearance on hover
    %   Add this custom UI component to your app to create a button whose
    %   border changes when a user hovers their pointer over it.

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        HTMLButton  matlab.ui.control.HTML
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ButtonPushed
    end

    properties (Access = public)
        FontColor (1,1) string {mustBeMember(FontColor, ["black","white"])} = "black";
        Text string {mustBeText(Text)} = "Button";
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.HTMLButton.Data = struct('Clicked',false);
        end

        % Data changed function: HTMLButton
        function HTMLButtonDataChanged(comp, event)
            if comp.HTMLButton.Data.Clicked
                comp.HTMLButton.Data.Clicked = 'false';
                drawnow
                notify(comp, 'ButtonPushed');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Convert BackgroundColor to rgb value for CSS
            r = num2str(ceil(comp.BackgroundColor(1)*255));
            g = num2str(ceil(comp.BackgroundColor(2)*255));
            b = num2str(ceil(comp.BackgroundColor(3)*255));
            rgb = "rgb(" + r + ", " + g + ", " + b + ")";

            % Update HTML component properties
            comp.HTMLButton.Data.Color = rgb;
            comp.HTMLButton.Data.FontColor = comp.FontColor;
            comp.HTMLButton.Data.Text = comp.Text;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 100 22];
            comp.BackgroundColor = [1 1 1];
            comp.GridLayout = uigridlayout(comp);
            comp.HTMLButton = uihtml(comp.GridLayout);
            comp.HTMLButton.HTMLSource = 'Button.html';
            comp.HTMLButton.DataChangedFcn = matlab.apps.createCallbackFcn(comp, @HTMLButtonDataChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end