classdef MatlabFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProcesamientodeimagenesLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SaveEdit                      matlab.ui.control.EditField
        GuardarButton                 matlab.ui.control.Button
        atenuadoButton                matlab.ui.control.Button
        FiltrosLabel                  matlab.ui.control.Label
        SuavizadoButton               matlab.ui.control.Button
        ReflectarLabel_2              matlab.ui.control.Label
        Switch2                       matlab.ui.control.Switch
        BlancoyNegroLabel             matlab.ui.control.Label
        Switch1                       matlab.ui.control.Switch
        GirarButtonGroup              matlab.ui.container.ButtonGroup
        Button_4                      matlab.ui.control.RadioButton
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        AjustesLabel                  matlab.ui.control.Label
        Path                          matlab.ui.control.EditField
        SeleccionarButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        im; % Description
        imgray;
        normalPosition = 0;
        SaveFile=0;
        imf;
        sim;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
                
            [rawName, rawPath] = uigetfile({'*.jpg*'},'Seleccionar');
            fullName = [rawPath,rawName];

          
            app.Path.Value = fullName;
            app.im = imread(fullName);
            
             imshow(app.im,'Parent',app.UIAxes);


        end

        % Value changed function: Switch1
        function Switch1ValueChanged(app, event)
           
            value = app.Switch1.Value;
             switch value
        case 'On'
          app.imgray = rgb2gray(app.im);
             imshow(app.imgray,'Parent',app.UIAxes);
             app.SaveFile=1;
        case 'Off'
             imshow(app.im,'Parent',app.UIAxes);
                 app.SaveFile=0;
        end


        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            if(app.GirarButtonGroup.SelectedObject.Text == "90°"|app.GirarButtonGroup.SelectedObject.Text == "270°")
            app.im = flip(app.im ,1);
            app.imgray = flip(app.imgray,1)
            else
                    app.im = flip(app.im ,2);
            app.imgray = flip(app.imgray,2)
            end

            Switch1ValueChanged(app, event)

        end

        % Callback function
        function AnguloSliderValueChanging(app, event)
            %changingValue = event.Value;
            %app.imgray = imrotate(app.imgray,40,'crop');
            %app.im = imrotate(app.im,40,'crop');
            %Switch1ValueChanged(app, event)
        end

        % Callback function
        function AnguloSliderValueChanged(app, event)
            value = app.AnguloSlider.Value;
            angleValue = -value+app.normalPosition;
                  app.imgray = imrotate(app.imgray,angleValue);
            app.im = imrotate(app.im,angleValue);
           
           
            app.normalPosition = -value;
            Switch1ValueChanged(app, event)
        end

        % Selection changed function: GirarButtonGroup
        function GirarButtonGroupSelectionChanged(app, event)
            selectedButton = app.GirarButtonGroup.SelectedObject.Text;
            switch selectedButton
    case '0°' %where "Button1" is the name of the first radio button
               angle = 0;                   
    case '90°'
         angle = -90;
         case '180°'
        angle = -180;
          case '270°'
         angle = -270;

            end
             angleValue = -app.normalPosition+angle;
             app.normalPosition = angle;
          
                app.imgray = imrotate(app.imgray,angleValue);
            app.im = imrotate(app.im,angleValue); 
            Switch1ValueChanged(app, event)
        end

        % Button pushed function: SuavizadoButton
        function SuavizadoButtonPushed(app, event)
          if(app.Switch1.Value=="On")
            Input_image = app.imgray; 
            else
             Input_image = app.im;
            end
           
Noisy_image = imnoise(Input_image,'salt & pepper',0.03);
h = fspecial('average',5);
app.sim = imfilter(Noisy_image, h);
    app.SaveFile=2;

  imshow(app.sim,'Parent',app.UIAxes) ;


        end

        % Button pushed function: atenuadoButton
        function atenuadoButtonPushed(app, event)
            if(app.Switch1.Value=="On")
            imo = app.imgray; 
            else
             imo = app.im;
            end
             Input_image = app.im;
             Input_image = rgb2gray(double(Input_image)/255);
           
             h = firpm(16,[0 0.1 0.3 1],[0 0 1 1]);
             h = ftrans2(h);
             app.imf = filter2(h,Input_image);
                 app.SaveFile=3;
           imshow(app.imf, [min(app.imf(:)) max(app.imf(:))],'Parent',app.UIAxes);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            savename=app.SaveEdit.Value;
    
            switch app.SaveFile
                case 0
                    fimage=app.imgray;
               case 1
                    fimage=app.im;
                  case 2
                    fimage=app.sim;
                  case 3
                      
                    fimage=rescale(app.imf, 0, 1);     
            end
            if(savename ~= "")
                imwrite(fimage,(savename + ".jpg"));
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.Path = uieditfield(app.UIFigure, 'text');
            app.AjustesLabel = uilabel(app.UIFigure);
            app.AjustesLabel.Text = 'Ajustes';
            app.GirarButtonGroup = uibuttongroup(app.UIFigure);
            app.GirarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GirarButtonGroupSelectionChanged, true);
            app.GirarButtonGroup.Title = 'Girar';
            app.Button = uiradiobutton(app.GirarButtonGroup);
            app.Button.Text = '0°';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.GirarButtonGroup);
            app.Button_2.Text = '90°';
            app.Button_3 = uiradiobutton(app.GirarButtonGroup);
            app.Button_3.Text = '180°';
            app.Button_4 = uiradiobutton(app.GirarButtonGroup);
            app.Button_4.Text = '270°';
            app.Switch1 = uiswitch(app.UIFigure, 'slider');
            app.Switch1.ValueChangedFcn = createCallbackFcn(app, @Switch1ValueChanged, true);
            app.BlancoyNegroLabel = uilabel(app.UIFigure);
            app.BlancoyNegroLabel.Text = 'Blanco y Negro';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.ReflectarLabel_2 = uilabel(app.UIFigure);
            app.ReflectarLabel_2.Text = 'Reflectar:';
            app.SuavizadoButton = uibutton(app.UIFigure, 'push');
            app.SuavizadoButton.ButtonPushedFcn = createCallbackFcn(app, @SuavizadoButtonPushed, true);
            app.SuavizadoButton.Text = 'Suavizado';
            app.FiltrosLabel = uilabel(app.UIFigure);
            app.FiltrosLabel.Text = 'Filtros';
            app.atenuadoButton = uibutton(app.UIFigure, 'push');
            app.atenuadoButton.ButtonPushedFcn = createCallbackFcn(app, @atenuadoButtonPushed, true);
            app.atenuadoButton.Text = 'atenuado';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.SaveEdit = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Horizontal-Line-Free-PNG-Image.png';
            app.ProcesamientodeimagenesLabel = uilabel(app.UIFigure);
            app.ProcesamientodeimagenesLabel.Text = 'Procesamiento de imagenes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end